classdef Niepewnosci < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        OaplikacjiMenu              matlab.ui.container.Menu
        ImporttabeliMenu            matlab.ui.container.Menu
        niep_sum                    matlab.ui.control.Label
        SumaniepewnociuABLabel      matlab.ui.control.Label
        regresja_3                  matlab.ui.control.Label
        regresja_2                  matlab.ui.control.Label
        WspczynnikcLabel            matlab.ui.control.Label
        WspczynnikbLabel            matlab.ui.control.Label
        regresja                    matlab.ui.control.Label
        WspczynnikaLabel            matlab.ui.control.Label
        GranicaP                    matlab.ui.control.NumericEditField
        GranicaprawaEditFieldLabel  matlab.ui.control.Label
        GranicaL                    matlab.ui.control.NumericEditField
        GranicalewaEditFieldLabel   matlab.ui.control.Label
        DopasujpunktyButton         matlab.ui.control.Button
        streg                       matlab.ui.control.Spinner
        StopieregresjiSpinnerLabel  matlab.ui.control.Label
        niep_A                      matlab.ui.control.Label
        uALabel                     matlab.ui.control.Label
        ObliczniepAButton           matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        WartociparametrwPanel       matlab.ui.container.Panel
        Sym_5                       matlab.ui.control.Label
        Sym_4                       matlab.ui.control.Label
        Sym_3                       matlab.ui.control.Label
        Sym_2                       matlab.ui.control.Label
        Sym_1                       matlab.ui.control.Label
        EditFieldLabel_6            matlab.ui.control.Label
        EditFieldLabel_5            matlab.ui.control.Label
        EditFieldLabel_4            matlab.ui.control.Label
        EditFieldLabel_3            matlab.ui.control.Label
        EditFieldLabel_2            matlab.ui.control.Label
        E_valu                      matlab.ui.control.NumericEditField
        D_valu                      matlab.ui.control.NumericEditField
        C_valu                      matlab.ui.control.NumericEditField
        B_valu                      matlab.ui.control.NumericEditField
        A_valu                      matlab.ui.control.NumericEditField
        uBLabel                     matlab.ui.control.Label
        niep_B                      matlab.ui.control.Label
        WitajwprogramiedoobliczanianiepewnociLabel  matlab.ui.control.Label
        WzrFunkcjiPanel             matlab.ui.container.Panel
        UpewnijsiewprowadzonezmienneszdefiniowanewtabeliniepewnociLabel  matlab.ui.control.Label
        wzorFun                     matlab.ui.control.EditField
        FunkcjaEditFieldLabel       matlab.ui.control.Label
        IloparametrwSpinner         matlab.ui.control.Spinner
        IloparametrwSpinnerLabel    matlab.ui.control.Label
        ObliczNiepewnoBButton       matlab.ui.control.Button
        NiepewnocitypuBPanel        matlab.ui.container.Panel
        Label_6                     matlab.ui.control.Label
        Es                          matlab.ui.control.EditField
        uEditField_2Label_5         matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Ds                          matlab.ui.control.EditField
        uEditField_2Label_4         matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Cs                          matlab.ui.control.EditField
        uEditField_2Label_3         matlab.ui.control.Label
        Bs                          matlab.ui.control.EditField
        uEditField_2Label_2         matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        u_A                         matlab.ui.control.NumericEditField
        EditFieldLabel              matlab.ui.control.Label
        As                          matlab.ui.control.EditField
        uEditField_2Label           matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        u_E                         matlab.ui.control.NumericEditField
        EditField_6Label            matlab.ui.control.Label
        u_D                         matlab.ui.control.NumericEditField
        EditField_5Label            matlab.ui.control.Label
        u_C                         matlab.ui.control.NumericEditField
        EditField_4Label            matlab.ui.control.Label
        u_B                         matlab.ui.control.NumericEditField
        EditField_3Label            matlab.ui.control.Label
        Wykres                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
         A
         B
         C
         D
         E
         uA_val
         uB_val
         uC_val
         uD_val
         uE_val
         A_val
         B_val
         C_val
         D_val
         E_val
         func
         Ilosc
         Tabela
         wynikA
         wynikB
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: NiepewnocitypuBPanel
        function NiepewnocitypuBPanelSizeChanged(app, event)
           
        end

        % Button pushed function: ObliczNiepewnoBButton
        function ObliczNiepewnoBButtonPushed(app, event)
            
            %obliczanie niepewnosci z prawa przenoszenia niepewnosci
           if isequal(app.Ilosc,1) %Przypadek pierwszy kiedy liczba zmiennych =1.
            app.func=str2sym(app.wzorFun.Value); %program przyjmuje funckje z okienka do jej wprowadzenia w formacie zmiennej symbolicznej
            app.uA_val=app.u_A.Value; %program przyjmuje wartosc niepewnosci zmiennej z odpowiedniego okienka
            
            dA=diff(app.func, app.A); %funkcja jest rozniczkowana po zmiennej 
            
            dA_val=subs(dA,app.A,app.A_val); %wartosc po podstawieniu zmiennych zrozniczkowanej funkcji 
            
            wynik=sqrt((dA_val * app.uA_val)^2); %wynik to ostateczne wyliczenie niepewnosci z prawa przenoszenie niepewnosci 
            app.wynikB=double(wynik); %wartosc jest zamieniana z formatu sym na double
            app.niep_B.Text=num2str(app.wynikB); %wyswietlenie ostatecznego wyniku
            
           end
            
           if isequal(app.Ilosc,2) %W pozostalych przypadkach kiedy liczba zmiennych >1w program dziala na podobnej zasadzie  
            app.func=str2sym(app.wzorFun.Value); %jak w przypadku liczby zmiennych = 1 z ta roznica ze operacje pobierania
            app.uA_val=app.u_A.Value; %zmiennych oraz roznikowanie po nich sa robione dla wiekszej ilosci zmiennych
            app.uB_val=app.u_B.Value;
            
            dA=diff(app.func, app.A);
            dB=diff(app.func, app.B);

            dA_val=subs(dA, [app.A, app.B], [app.A_val, app.B_val]);
            dB_val=subs(dB, [app.A, app.B], [app.A_val, app.B_val]);

            %najwieksza roznica jest w przypadku ostatecznego wyniku. 
            % formula jest zgodna z prawem przenoszenia niepewnosci 

            wynik=sqrt((dA_val * app.uA_val)^2 + (dB_val * app.uB_val)^2); 
            app.wynikB=double(wynik);
            app.niep_B.Text=num2str(app.wynikB);
           end
           
            if isequal(app.Ilosc,3) %przy reszcie przypadków roznych wartosci ilosc zmiennych schet jest blizniaczo podobny
            app.func=str2sym(app.wzorFun.Value);
            app.uA_val=app.u_A.Value;
            app.uB_val=app.u_B.Value;
            app.uC_val=app.u_C.Value;
            
            dA=diff(app.func, app.A);
            dB=diff(app.func, app.B);
            dC=diff(app.func, app.C);

            dA_val=subs(dA, [app.A, app.B, app.C], [app.A_val, app.B_val, app.C_val]);
            dB_val=subs(dB, [app.A, app.B, app.C], [app.A_val, app.B_val, app.C_val]);
            dC_val=subs(dC, [app.A, app.B, app.C], [app.A_val, app.B_val, app.C_val]);

            wynik=sqrt((dA_val * app.uA_val)^2 + (dB_val * app.uB_val)^2 + (dC_val * app.uC_val)^2);
            app.wynikB=double(wynik);
            app.niep_B.Text=num2str(app.wynikB);
           end
           
            if isequal(app.Ilosc,4)
            app.func=str2sym(app.wzorFun.Value);
            app.uA_val=app.u_A.Value;
            app.uB_val=app.u_B.Value;
            app.uC_val=app.u_C.Value;
            app.uD_val=app.u_D.Value;

            dA=diff(app.func, app.A);
            dB=diff(app.func, app.B);
            dC=diff(app.func, app.C);
            dD=diff(app.func, app.D);

            dA_val=subs(dA, [app.A, app.B, app.C, app.D], [app.A_val, app.B_val, app.C_val, app.D_val]);
            dB_val=subs(dB, [app.A, app.B, app.C, app.D], [app.A_val, app.B_val, app.C_val, app.D_val]);
            dC_val=subs(dC, [app.A, app.B, app.C, app.D], [app.A_val, app.B_val, app.C_val, app.D_val]);
            dD_val=subs(dD, [app.A, app.B, app.C, app.D], [app.A_val, app.B_val, app.C_val, app.D_val]);

            wynik=sqrt((dA_val * app.uA_val)^2 + (dB_val * app.uB_val)^2 + (dC_val * app.uC_val)^2 + (dD_val * app.uD_val)^2);
            app.wynikB=double(wynik);
            app.niep_B.Text=num2str(app.wynikB);
           end

            if isequal(app.Ilosc,5)
            app.func=str2sym(app.wzorFun.Value);
            app.uA_val=app.u_A.Value;
            app.uB_val=app.u_B.Value;
            app.uC_val=app.u_C.Value;
            app.uD_val=app.u_D.Value;
            app.uE_val=app.u_E.Value;
            
            dA=diff(app.func, app.A);
            dB=diff(app.func, app.B);
            dC=diff(app.func, app.C);
            dD=diff(app.func, app.D);
            dE=diff(app.func, app.E);

            dA_val=subs(dA, [app.A, app.B, app.C, app.D, app.E], [app.A_val, app.B_val, app.C_val, app.D_val, app.E_val]);
            dB_val=subs(dB, [app.A, app.B, app.C, app.D, app.E], [app.A_val, app.B_val, app.C_val, app.D_val, app.E_val]);
            dC_val=subs(dC, [app.A, app.B, app.C, app.D, app.E], [app.A_val, app.B_val, app.C_val, app.D_val, app.E_val]);
            dD_val=subs(dD, [app.A, app.B, app.C, app.D, app.E], [app.A_val, app.B_val, app.C_val, app.D_val, app.E_val]);
            dE_val=subs(dE, [app.A, app.B, app.C, app.D, app.E], [app.A_val, app.B_val, app.C_val, app.D_val, app.E_val]);

            wynik=sqrt((dA_val * app.uA_val)^2 + (dB_val * app.uB_val)^2 + (dC_val * app.uC_val)^2 + (dD_val * app.uD_val)^2 + (dE_val * app.uE_val)^2);
            app.wynikB=double(wynik);
            app.niep_B.Text=num2str(app.wynikB);
 
            end

            %suma niepewnosci, dziala automatycznie 
            if (app.niep_A.Text=="0") %w przypadku niep A=0 suma to jest po prostu wyswietlenie samej niepewnosci B
            app.niep_sum.Text=num2str(app.wynikB);
            else %jesli niepA nie jest zerowa to program sumuje dwie zmienne i je wyswietla
            app.niep_sum.Text=num2str(app.wynikB+app.wynikA);
            end

        end

        % Value changed function: u_A
        function u_AValueChanged3(app, event)
            value = app.u_A.Value;
            app.uA_val=value;%pobranie wartosci niepewnosci paramentru
        end

        % Value changed function: u_E
        function u_EValueChanged(app, event)
            value = app.u_E.Value;%pobranie wartosci niepewnosci paramentru
            app.uE_val=value;
        end

        % Value changed function: u_D
        function u_DValueChanged(app, event)
            value = app.u_D.Value;
            app.uD_val=value;%pobranie wartosci niepewnosci paramentru
        end

        % Value changed function: u_B
        function u_BValueChanged(app, event)
            value = app.u_B.Value;
            app.uB_val=value; %pobranie wartosci niepewnosci paramentru
        end

        % Value changed function: wzorFun
        function wzorFunValueChanged(app, event)
            value = app.wzorFun.Value;
            app.func = value; %pobranie wzoru funkcji do zmiennej globalej 
        end

        % Value changing function: wzorFun
        function wzorFunValueChanging(app, event)
    
        end

        % Value changed function: As
        function AsValueChanged(app, event)
            value = app.As.Value; %podsatwienie symbolu zmiennej w okienku do wprowadzenia wart zmiennej (zamiast pytajnika)
            app.A = str2sym(value);
            app.Sym_1.Text = value;
        end

        % Value changed function: A_valu
        function A_valuValueChanged(app, event)
            value = app.A_valu.Value;
            app.A_val=value; %pobranie wartosci paramentru
        end

        % Value changed function: Cs
        function CsValueChanged(app, event)
            value = app.Cs.Value;%podsatwienie symbolu zmiennej w okienku do wprowadzenia wart zmiennej (zamiast pytajnika)
            app.C = str2sym(value);
            app.Sym_3.Text = value;
        end

        % Value changed function: Ds
        function DsValueChanged(app, event)
            value = app.Ds.Value;
            app.D = str2sym(value);%podsatwienie symbolu zmiennej w okienku do wprowadzenia wart zmiennej (zamiast pytajnika)
            app.Sym_4.Text = value;
        end

        % Value changed function: Es
        function EsValueChanged(app, event)
            value = app.Es.Value;%podsatwienie symbolu zmiennej w okienku do wprowadzenia wart zmiennej (zamiast pytajnika)
            app.E = str2sym(value);
            app.Sym_5.Text = value;
        end

        % Value changed function: u_C
        function u_CValueChanged(app, event)
            value = app.u_C.Value;
            app.uC_val=value;%pobranie wartosci niepewnosci paramentru
        end

        % Value changed function: Bs
        function BsValueChanged(app, event)
            value = app.Bs.Value;%podsatwienie symbolu zmiennej w okienku do wprowadzenia wart zmiennej (zamiast pytajnika)
            app.B = str2sym(value);
            app.Sym_2.Text = value;
        end

        % Value changed function: B_valu
        function B_valuValueChanged(app, event)
            value = app.B_valu.Value;
            app.B_val=value; %przypisanie zmiennej z okienka do wartosci globalnej
        end

        % Value changed function: IloparametrwSpinner
        function IloparametrwSpinnerValueChanged(app, event)
            value = app.IloparametrwSpinner.Value;
            app.Ilosc = value; %ten schemat odpowiada za dostepnosc pol do zmiennych. Jest on po to by wyeliminowac czesc bledow
            %zwiazanych wprowadzeniem za duzej ilosci zmienych
            
            app.A_valu.Enable = "off"; %przy uruchomieniu programu wszystkie okna dla zmiennych sa nieaktywne
            app.B_valu.Enable = "off";
            app.C_valu.Enable = "off";
            app.D_valu.Enable = "off";
            app.E_valu.Enable = "off";

            app.As.Enable = "off";
            app.Bs.Enable = "off";
            app.Cs.Enable = "off";
            app.Ds.Enable = "off";
            app.Es.Enable = "off";

            app.u_A.Enable = "off";
            app.u_B.Enable = "off";
            app.u_C.Enable = "off";
            app.u_D.Enable = "off";
            app.u_E.Enable = "off";

            if isequal(app.Ilosc,1) %wraz ze wzrostem liczby na liczniku okienka dla odpowieniej zmiennej sa odblokowywane
            app.A_valu.Enable = "on";

            app.As.Enable = "on";

            app.u_A.Enable = "on";
            end
            
            if isequal(app.Ilosc,2) %pozostala czesc kodu, dla innych przypadkow wartosci spinnera
                % jest blizniaczo podobna 
            app.A_valu.Enable = "on";
            app.B_valu.Enable = "on";

            app.As.Enable = "on";
            app.Bs.Enable = "on";

            app.u_A.Enable = "on";
            app.u_B.Enable = "on";
            end

            if isequal(app.Ilosc,3)
            app.A_valu.Enable = "on";
            app.B_valu.Enable = "on";
            app.C_valu.Enable = "on";

            app.As.Enable = "on";
            app.Bs.Enable = "on";
            app.Cs.Enable = "on";

            app.u_A.Enable = "on";
            app.u_B.Enable = "on";
            app.u_C.Enable = "on";
            end

            if isequal(app.Ilosc,4)
            app.A_valu.Enable = "on";
            app.B_valu.Enable = "on";
            app.C_valu.Enable = "on";
            app.D_valu.Enable = "on";

            app.As.Enable = "on";
            app.Bs.Enable = "on";
            app.Cs.Enable = "on";
            app.Ds.Enable = "on";

            app.u_A.Enable = "on";
            app.u_B.Enable = "on";
            app.u_C.Enable = "on";
            app.u_D.Enable = "on";
            end

            if isequal(app.Ilosc,5)
            app.A_valu.Enable = "on";
            app.B_valu.Enable = "on";
            app.C_valu.Enable = "on";
            app.D_valu.Enable = "on";
            app.E_valu.Enable = "on";

            app.As.Enable = "on";
            app.Bs.Enable = "on";
            app.Cs.Enable = "on";
            app.Ds.Enable = "on";
            app.Es.Enable = "on";

            app.u_A.Enable = "on";
            app.u_B.Enable = "on";
            app.u_C.Enable = "on";
            app.u_D.Enable = "on";
            app.u_E.Enable = "on";
            end

        end

        % Value changed function: C_valu
        function C_valuValueChanged(app, event)
            value = app.C_valu.Value;
            app.C_val=value; %przypisanie zmiennej z okienka do wartosci globalnej
        end

        % Value changed function: D_valu
        function D_valuValueChanged(app, event)
            value = app.D_valu.Value;
            app.D_val=value;%przypisanie zmiennej z okienka do wartosci globalnej
        end

        % Value changed function: E_valu
        function E_valuValueChanged(app, event)
            value = app.E_valu.Value;
            app.E_val=value;%przypisanie zmiennej z okienka do wartosci globalnej
        end

        % Menu selected function: OaplikacjiMenu
        function OaplikacjiMenuSelected(app, event)
            omnie = sprintf(['Autor: W. Kedzior \n' ... %wyswietlenie okienka z informacjami do aplikacji
                                '\n \n' ...
                                'Program oblicza rozne typy niepewnosci oraz pozwala na wykonanie regresji liniowej \n \n' ...
                                '\n Niepewnosc typu B jest liczona z prawa przenoszenia niepewnosci. Spinnerem nalezy ustawic sobie ilosc zmiennych jaka jest uzyta we wzorze. \n ' ...
                                '\n Niepewnosc typu A jest obliczana z danych wprowadzonych w tabli w pliku.txt. przy pomocy wzoru na niepewnosc standardowa. \n' ...
                                '\n Obie niepewnosci sa automatycznie sumowane\n' ...
                                '\n Program umozliwia rowniez wykonanie regresji liniowej oraz kwadratowej. Dane nalezy wprowadzic pionowa po lewej stronie x, po prawej y. Program posiada zabezpieczenia przed nieprawidlowymi granicami funkcji']);
            uialert(app.UIFigure,omnie,'O mnie','Icon','info');
        end

        % Value changing function: As
        function AsValueChanging(app, event)

        end

        % Menu selected function: ImporttabeliMenu
        function ImporttabeliMenuSelected(app, event)
            [plik,sciezka]=uigetfile('*.txt');
            app.Tabela = readtable([sciezka,plik]);

            app.UITable.Data = app.Tabela; %kod potrzebny do zaladowania tabeli
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
         
        end

        % Button pushed function: ObliczniepAButton
        function ObliczniepAButtonPushed(app, event)
            wier = height(app.Tabela); %pobranie wymiarow tabeli aby zidentyfikowac czy pobrane pomiary sa pionowo
            kol = width(app.Tabela); %czy poziomo, schemat nie zadziala kiedy jeden z wymiarow nie jest rowny 1
            % co pozwala pominac bledy
            
            %obliczanie niepewnosci standardowej
            if (wier > 1) && (kol == 1) %przypadek kiedy zmienne sa ustawione pionowo
            Dane = app.Tabela; %pobranie danych z tabeli
            odch = std(Dane{:,:},1,"all"); %odchylenie std jest liczone z funkcji wbudowanej w matlabie Dane sa tutaj w postaci macierzy
            app.wynikA=sqrt(1/wier)*odch; %odchylenie jest dodatkowo mnozone przez 1/sqrt(n) aby uzyskac niepewnosc standardowa
            app.niep_A.Text = num2str(app.wynikA); %wyswietlenie wartosci niepewnosci 
            
            end

            if (kol > 1) && (wier == 1) %przypadek kiedy zmienne sa ustawione poziomo, schemat jest blizniaczo podobny jak wyzej
            Dane = app.Tabela;
            odch = std(Dane{:,:},1,"all");
            app.wynikA=sqrt(1/kol)*odch; %roznica jest taka, ze liczba elementow to liczba kolumn
            app.niep_A.Text = num2str(app.wynikA);
     
            end
            
            %suma niepewnosci, dziala automatycznie 
            if (app.niep_B.Text=="0") %w przypadku niep B=0 suma to jest po prostu wyswietlenie samej niepewnosci A
            app.niep_sum.Text=num2str(app.wynikA);
            else %jesli niepA nie jest zerowa to program sumuje dwie zmienne i je wyswietla
            app.niep_sum.Text=num2str(app.wynikB+app.wynikA);
            end
           
        end

        % Button down function: Wykres
        function WykresButtonDown(app, event)
            
        end

        % Button pushed function: DopasujpunktyButton
        function DopasujpunktyButtonPushed(app, event)
            %schemat regresji
            n=app.streg.Value; %pobranie stopnia regresji
            Dane = app.Tabela; %pobrane danych do regresji
            x=Dane{:,1};
            y=Dane{:,2}; 
            f1 = polyfit(x, y, n); %dopasowanie w postaci wzoru
            app.regresja.Text=num2str(f1(1,1)); %wyswietlenie wspolczynnika a
            app.regresja_2.Text=num2str(f1(1,2)); %wyswietlenie wspolczynnika b
            a=f1(1,1); %pobranie wartosci wspolczynnika a
            b=f1(1,2); %pobranie wartosci wspolczynnika b
            app.regresja_3.Text="0"; %dla regresji liniowej wspl c zawsze jest rowny 
            % zero wiec wyswietlane jest 0, gdyby uzytkownik najpierw
            % zrobil regresje kwadratowa
            f2= @(x) x*a+b; %funkcja anonimowa dla funkcji liniowej
            if (n==2) %przypadek kiedy licznik jest ustawiony na regresje stopnia 2
            app.regresja_3.Text=num2str(f1(1,3)); %wyswietlany jest dodatkowo wspl c
            c=f1(1,3); %pobranie wartosci wspl c
            f2= @(x) a*(x^2)+b*x+c; %funkcja anonimowa dla funkcji kwadratowej
            end

            %zabezpieczenie przed nieprawidlowymi branicami wykresu
            if isequal(app.GranicaL.Value,app.GranicaP.Value) || app.GranicaL.Value>app.GranicaP.Value  
                a = sprintf('Granice wykresu sa sobie rowne - Popraw je');
            uialert(app.UIFigure,a,'Blad');
            else
                ezplot(app.Wykres,f2,[app.GranicaL.Value,app.GranicaP.Value]); %wyswietlenie wykresu przy poprawnych granicach
            
            end
        end

        % Value changed function: streg
        function stregValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OaplikacjiMenu = uimenu(app.Menu);
            app.OaplikacjiMenu.MenuSelectedFcn = createCallbackFcn(app, @OaplikacjiMenuSelected, true);
            app.OaplikacjiMenu.Text = 'O aplikacji';
            app.ImporttabeliMenu = uimenu(app.Menu);
            app.ImporttabeliMenu.MenuSelectedFcn = createCallbackFcn(app, @ImporttabeliMenuSelected, true);
            app.ImporttabeliMenu.Text = 'Import tabeli';
            app.Wykres = uiaxes(app.UIFigure);
            title(app.Wykres, 'Regresja')
            xlabel(app.Wykres, 'X')
            ylabel(app.Wykres, 'Y')
            zlabel(app.Wykres, 'Z')
            app.Wykres.ButtonDownFcn = createCallbackFcn(app, @WykresButtonDown, true);
            app.NiepewnocitypuBPanel = uipanel(app.UIFigure);
            app.NiepewnocitypuBPanel.Title = 'Niepewności typu B:';
            app.NiepewnocitypuBPanel.SizeChangedFcn = createCallbackFcn(app, @NiepewnocitypuBPanelSizeChanged, true);
            app.EditField_3Label = uilabel(app.NiepewnocitypuBPanel);
            app.EditField_3Label.Text = '=';
            app.u_B = uieditfield(app.NiepewnocitypuBPanel, 'numeric');
            app.u_B.ValueChangedFcn = createCallbackFcn(app, @u_BValueChanged, true);
            app.u_B.Enable = 'off';
            app.EditField_4Label = uilabel(app.NiepewnocitypuBPanel);
            app.EditField_4Label.Text = '=';
            app.u_C = uieditfield(app.NiepewnocitypuBPanel, 'numeric');
            app.u_C.ValueChangedFcn = createCallbackFcn(app, @u_CValueChanged, true);
            app.u_C.Enable = 'off';
            app.EditField_5Label = uilabel(app.NiepewnocitypuBPanel);
            app.EditField_5Label.Text = '=';
            app.u_D = uieditfield(app.NiepewnocitypuBPanel, 'numeric');
            app.u_D.ValueChangedFcn = createCallbackFcn(app, @u_DValueChanged, true);
            app.u_D.Enable = 'off';
            app.EditField_6Label = uilabel(app.NiepewnocitypuBPanel);
            app.EditField_6Label.Text = '=';
            app.u_E = uieditfield(app.NiepewnocitypuBPanel, 'numeric');
            app.u_E.ValueChangedFcn = createCallbackFcn(app, @u_EValueChanged, true);
            app.u_E.Enable = 'off';
            app.Label_2 = uilabel(app.NiepewnocitypuBPanel);
            app.Label_2.Text = ')';
            app.uEditField_2Label = uilabel(app.NiepewnocitypuBPanel);
            app.uEditField_2Label.Text = 'u(';
            app.As = uieditfield(app.NiepewnocitypuBPanel, 'text');
            app.As.ValueChangedFcn = createCallbackFcn(app, @AsValueChanged, true);
            app.As.ValueChangingFcn = createCallbackFcn(app, @AsValueChanging, true);
            app.As.Enable = 'off';
            app.EditFieldLabel = uilabel(app.NiepewnocitypuBPanel);
            app.EditFieldLabel.Text = '=';
            app.u_A = uieditfield(app.NiepewnocitypuBPanel, 'numeric');
            app.u_A.ValueChangedFcn = createCallbackFcn(app, @u_AValueChanged3, true);
            app.u_A.Enable = 'off';
            app.Label_3 = uilabel(app.NiepewnocitypuBPanel);
            app.Label_3.Text = ')';
            app.uEditField_2Label_2 = uilabel(app.NiepewnocitypuBPanel);
            app.uEditField_2Label_2.Text = 'u(';
            app.Bs = uieditfield(app.NiepewnocitypuBPanel, 'text');
            app.Bs.ValueChangedFcn = createCallbackFcn(app, @BsValueChanged, true);
            app.Bs.Enable = 'off';
            app.uEditField_2Label_3 = uilabel(app.NiepewnocitypuBPanel);
            app.uEditField_2Label_3.Text = 'u(';
            app.Cs = uieditfield(app.NiepewnocitypuBPanel, 'text');
            app.Cs.ValueChangedFcn = createCallbackFcn(app, @CsValueChanged, true);
            app.Cs.Enable = 'off';
            app.Label_4 = uilabel(app.NiepewnocitypuBPanel);
            app.Label_4.Text = ')';
            app.uEditField_2Label_4 = uilabel(app.NiepewnocitypuBPanel);
            app.uEditField_2Label_4.Text = 'u(';
            app.Ds = uieditfield(app.NiepewnocitypuBPanel, 'text');
            app.Ds.ValueChangedFcn = createCallbackFcn(app, @DsValueChanged, true);
            app.Ds.Enable = 'off';
            app.Label_5 = uilabel(app.NiepewnocitypuBPanel);
            app.Label_5.Text = ')';
            app.uEditField_2Label_5 = uilabel(app.NiepewnocitypuBPanel);
            app.uEditField_2Label_5.Text = 'u(';
            app.Es = uieditfield(app.NiepewnocitypuBPanel, 'text');
            app.Es.ValueChangedFcn = createCallbackFcn(app, @EsValueChanged, true);
            app.Es.Enable = 'off';
            app.Label_6 = uilabel(app.NiepewnocitypuBPanel);
            app.Label_6.Text = ')';
            app.WzrFunkcjiPanel = uipanel(app.UIFigure);
            app.WzrFunkcjiPanel.Title = 'Wzór Funkcji';
            app.ObliczNiepewnoBButton = uibutton(app.WzrFunkcjiPanel, 'push');
            app.ObliczNiepewnoBButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczNiepewnoBButtonPushed, true);
            app.ObliczNiepewnoBButton.Text = 'Oblicz Niepewność B';
            app.IloparametrwSpinnerLabel = uilabel(app.WzrFunkcjiPanel);
            app.IloparametrwSpinnerLabel.Text = 'Ilość parametrów';
            app.IloparametrwSpinner = uispinner(app.WzrFunkcjiPanel);
            app.IloparametrwSpinner.Limits = [0 5];
            app.IloparametrwSpinner.ValueChangedFcn = createCallbackFcn(app, @IloparametrwSpinnerValueChanged, true);
            app.FunkcjaEditFieldLabel = uilabel(app.WzrFunkcjiPanel);
            app.FunkcjaEditFieldLabel.Text = 'Funkcja';
            app.wzorFun = uieditfield(app.WzrFunkcjiPanel, 'text');
            app.wzorFun.ValueChangedFcn = createCallbackFcn(app, @wzorFunValueChanged, true);
            app.wzorFun.ValueChangingFcn = createCallbackFcn(app, @wzorFunValueChanging, true);
            app.UpewnijsiewprowadzonezmienneszdefiniowanewtabeliniepewnociLabel = uilabel(app.WzrFunkcjiPanel);
            app.UpewnijsiewprowadzonezmienneszdefiniowanewtabeliniepewnociLabel.Interpreter = 'html';
            app.UpewnijsiewprowadzonezmienneszdefiniowanewtabeliniepewnociLabel.Text = {'Upewnij się że wprowadzone zmienne są '; 'zdefiniowane w tabeli niepewności! Nie nazywaj zmiennych tak jak liczby tzn. "e", "i", "pi" itd.'; 'Pamiętaj żeby używać kropki a nie przecinka.'; 'Powodzenia na labach :)'};
            app.WitajwprogramiedoobliczanianiepewnociLabel = uilabel(app.UIFigure);
            app.WitajwprogramiedoobliczanianiepewnociLabel.Text = 'Witaj w programie do obliczania niepewności';
            app.niep_B = uilabel(app.UIFigure);
            app.niep_B.Text = '0';
            app.uBLabel = uilabel(app.UIFigure);
            app.uBLabel.Text = 'u(B)=';
            app.WartociparametrwPanel = uipanel(app.UIFigure);
            app.WartociparametrwPanel.Title = 'Wartości parametrów';
            app.A_valu = uieditfield(app.WartociparametrwPanel, 'numeric');
            app.A_valu.ValueChangedFcn = createCallbackFcn(app, @A_valuValueChanged, true);
            app.A_valu.Enable = 'off';
            app.B_valu = uieditfield(app.WartociparametrwPanel, 'numeric');
            app.B_valu.ValueChangedFcn = createCallbackFcn(app, @B_valuValueChanged, true);
            app.B_valu.Enable = 'off';
            app.C_valu = uieditfield(app.WartociparametrwPanel, 'numeric');
            app.C_valu.ValueChangedFcn = createCallbackFcn(app, @C_valuValueChanged, true);
            app.C_valu.Enable = 'off';
            app.D_valu = uieditfield(app.WartociparametrwPanel, 'numeric');
            app.D_valu.ValueChangedFcn = createCallbackFcn(app, @D_valuValueChanged, true);
            app.D_valu.Enable = 'off';
            app.E_valu = uieditfield(app.WartociparametrwPanel, 'numeric');
            app.E_valu.ValueChangedFcn = createCallbackFcn(app, @E_valuValueChanged, true);
            app.E_valu.Enable = 'off';
            app.EditFieldLabel_2 = uilabel(app.WartociparametrwPanel);
            app.EditFieldLabel_2.Text = '=';
            app.EditFieldLabel_3 = uilabel(app.WartociparametrwPanel);
            app.EditFieldLabel_3.Text = '=';
            app.EditFieldLabel_4 = uilabel(app.WartociparametrwPanel);
            app.EditFieldLabel_4.Text = '=';
            app.EditFieldLabel_5 = uilabel(app.WartociparametrwPanel);
            app.EditFieldLabel_5.Text = '=';
            app.EditFieldLabel_6 = uilabel(app.WartociparametrwPanel);
            app.EditFieldLabel_6.Text = '=';
            app.Sym_1 = uilabel(app.WartociparametrwPanel);
            app.Sym_1.Text = '?';
            app.Sym_2 = uilabel(app.WartociparametrwPanel);
            app.Sym_2.Text = '?';
            app.Sym_3 = uilabel(app.WartociparametrwPanel);
            app.Sym_3.Text = '?';
            app.Sym_4 = uilabel(app.WartociparametrwPanel);
            app.Sym_4.Text = '?';
            app.Sym_5 = uilabel(app.WartociparametrwPanel);
            app.Sym_5.Text = '?';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ObliczniepAButton = uibutton(app.UIFigure, 'push');
            app.ObliczniepAButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczniepAButtonPushed, true);
            app.ObliczniepAButton.Text = 'Oblicz niep. A';
            app.uALabel = uilabel(app.UIFigure);
            app.uALabel.Text = 'u(A)=';
            app.niep_A = uilabel(app.UIFigure);
            app.niep_A.Text = '0';
            app.StopieregresjiSpinnerLabel = uilabel(app.UIFigure);
            app.StopieregresjiSpinnerLabel.Text = 'Stopień regresji';
            app.streg = uispinner(app.UIFigure);
            app.streg.Limits = [1 2];
            app.streg.ValueChangedFcn = createCallbackFcn(app, @stregValueChanged, true);
            app.streg.Value = 1;
            app.DopasujpunktyButton = uibutton(app.UIFigure, 'push');
            app.DopasujpunktyButton.ButtonPushedFcn = createCallbackFcn(app, @DopasujpunktyButtonPushed, true);
            app.DopasujpunktyButton.Text = 'Dopasuj punkty';
            app.GranicalewaEditFieldLabel = uilabel(app.UIFigure);
            app.GranicalewaEditFieldLabel.Text = 'Granica lewa';
            app.GranicaL = uieditfield(app.UIFigure, 'numeric');
            app.GranicaprawaEditFieldLabel = uilabel(app.UIFigure);
            app.GranicaprawaEditFieldLabel.Text = 'Granica prawa';
            app.GranicaP = uieditfield(app.UIFigure, 'numeric');
            app.WspczynnikaLabel = uilabel(app.UIFigure);
            app.WspczynnikaLabel.Text = 'Współczynnik a=';
            app.regresja = uilabel(app.UIFigure);
            app.regresja.Text = '0';
            app.WspczynnikbLabel = uilabel(app.UIFigure);
            app.WspczynnikbLabel.Text = 'Współczynnik b=';
            app.WspczynnikcLabel = uilabel(app.UIFigure);
            app.WspczynnikcLabel.Text = 'Współczynnik c=';
            app.regresja_2 = uilabel(app.UIFigure);
            app.regresja_2.Text = '0';
            app.regresja_3 = uilabel(app.UIFigure);
            app.regresja_3.Text = '0';
            app.SumaniepewnociuABLabel = uilabel(app.UIFigure);
            app.SumaniepewnociuABLabel.Text = 'Suma niepewności u(A+B)= ';
            app.niep_sum = uilabel(app.UIFigure);
            app.niep_sum.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Niepewnosci

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end